classdef app2016 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        Panel                  matlab.ui.container.Panel          % Import
        Button                 matlab.ui.control.Button           % Import ...
        LabelEditField         matlab.ui.control.Label            % Signal ...
        EditField              matlab.ui.control.EditField       
        Button2                matlab.ui.control.Button           % Import ...
        LabelEditField2        matlab.ui.control.Label            % Velocit...
        EditField2             matlab.ui.control.EditField       
        Panel2                 matlab.ui.container.Panel          % Input P...
        Panel3                 matlab.ui.container.Panel          % Time
        LabelNumericEditField6 matlab.ui.control.Label            % Lower V...
        NumericEditField6      matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField7 matlab.ui.control.Label            % Upper V...
        NumericEditField7      matlab.ui.control.NumericEditField % [-Inf Inf]
        Panel4                 matlab.ui.container.Panel          % Velocity
        LabelNumericEditField8 matlab.ui.control.Label            % Lower V...
        NumericEditField8      matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField9 matlab.ui.control.Label            % Upper V...
        NumericEditField9      matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField2 matlab.ui.control.Label            % Value o...
        NumericEditField2      matlab.ui.control.NumericEditField % [-Inf Inf]
        Panel5                 matlab.ui.container.Panel          % Input S...
        LabelNumericEditField3 matlab.ui.control.Label            % Sensiti...
        NumericEditField3      matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField5 matlab.ui.control.Label            % Sensiti...
        NumericEditField5      matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField4 matlab.ui.control.Label            % Sensiti...
        NumericEditField4      matlab.ui.control.NumericEditField % [-Inf Inf]
        UIAxes                 matlab.ui.control.UIAxes           % GlevelsX
        UIAxes2                matlab.ui.control.UIAxes           % GlevelsZ
        UIAxes3                matlab.ui.control.UIAxes           % PSDX
        UIAxes4                matlab.ui.control.UIAxes           % GlevelsY
        UIAxes5                matlab.ui.control.UIAxes           % PSDY
        UIAxes6                matlab.ui.control.UIAxes           % PSDZ
        Panel6                 matlab.ui.container.Panel          % Plot fo...
        Button3                matlab.ui.control.Button           % Glevels
        Button4                matlab.ui.control.Button           % PSD
        Panel7                 matlab.ui.container.Panel          % Plot fo...
        Button5                matlab.ui.control.Button           % Glevels
        Button6                matlab.ui.control.Button           % PSD
        Panel8                 matlab.ui.container.Panel          % Use if ...
        Button7                matlab.ui.control.Button           % Glevels
        Button8                matlab.ui.control.Button           % PSD
        Button9                matlab.ui.control.Button           % Export ...
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button button pushed function
        function ButtonButtonPushed(app)
             [filename,path]=uigetfile();
            app.EditField.Value=filename;
        end

        % Button2 button pushed function
        function Button2ButtonPushed(app)
            [filename,path]=uigetfile();
            app.EditField2.Value=filename;
        end

        % Button3 button pushed function
        function Button3ButtonPushed(app)
            T=readtable(app.EditField.Value);
             
              
              indexl=(((app.NumericEditField2.Value)*(app.NumericEditField6.Value))+1);
              indexu=(((app.NumericEditField2.Value)*(app.NumericEditField7.Value))+1);
              timedata=T.(cell2mat(T.Properties.VariableNames(4)))((indexl):(indexu));
              voltagedatax=T.(cell2mat(T.Properties.VariableNames(1)))((indexl):(indexu));
              voltagedatay=T.(cell2mat(T.Properties.VariableNames(2)))((indexl):(indexu));
              voltagedataz=T.(cell2mat(T.Properties.VariableNames(3)))((indexl):(indexu));
                voltagedatax=voltagedatax./app.NumericEditField3.Value;
                voltagedatay=voltagedatay./app.NumericEditField4.Value;
                voltagedataz=voltagedataz./app.NumericEditField5.Value;
              plot(app.UIAxes,timedata,voltagedatax)
              plot(app.UIAxes4,timedata,voltagedatay)
              plot(app.UIAxes2,timedata,voltagedataz)
        end

        % Button4 button pushed function
        function Button4ButtonPushed(app)
              T=readtable(app.EditField.Value);
             
              
              indexl=(((app.NumericEditField2.Value)*(app.NumericEditField6.Value))+1);
              indexu=(((app.NumericEditField2.Value)*(app.NumericEditField7.Value))+1);
              timedata=T.(cell2mat(T.Properties.VariableNames(4)))((indexl):(indexu));
              voltagedatax=T.(cell2mat(T.Properties.VariableNames(1)))((indexl):(indexu));
              voltagedatay=T.(cell2mat(T.Properties.VariableNames(2)))((indexl):(indexu));
              voltagedataz=T.(cell2mat(T.Properties.VariableNames(3)))((indexl):(indexu));
                voltagedatax=voltagedatax./app.NumericEditField3.Value;
                voltagedatay=voltagedatay./app.NumericEditField4.Value;
                voltagedataz=voltagedataz./app.NumericEditField5.Value;
              [pxx,f] = pwelch( voltagedatax,500,0,500,app.NumericEditField2.Value);
            semilogx(app.UIAxes3,f,pxx)
            [pxx,f] = pwelch( voltagedatay,500,0,500,app.NumericEditField2.Value);
            semilogx(app.UIAxes5,f,pxx)
            [pxx,f] = pwelch( voltagedataz,500,0,500,app.NumericEditField2.Value);
            semilogx(app.UIAxes6,f,pxx)
        end

        % Button5 button pushed function
        function Button5ButtonPushed(app)
            
            A=readtable(app.EditField2.Value);
n=size(A.(cell2mat(A.Properties.VariableNames(1))));
  if (app.NumericEditField8.Value<=app.NumericEditField9.Value)
i=1;
while(i<(n(1)+1))
   if(A.(cell2mat(A.Properties.VariableNames(1)))(i)>=app.NumericEditField8.Value)
        upper_bound=A.(cell2mat(A.Properties.VariableNames(1)))(i);
        lower_bound=A.(cell2mat(A.Properties.VariableNames(1)))(i-1);
        break;
   end
   i=i+1;
end

if(abs(app.NumericEditField8.Value-upper_bound)>abs(app.NumericEditField8.Value-lower_bound))
app.NumericEditField8.Value=lower_bound;
else
    app.NumericEditField8.Value=upper_bound;

end
% for upperv
 j=1;
      
while(j<(n(1)+1))
   if(A.(cell2mat(A.Properties.VariableNames(1)))(j)>=app.NumericEditField9.Value)
        upper_bound2=A.(cell2mat(A.Properties.VariableNames(1)))(j);
        lower_bound2=A.(cell2mat(A.Properties.VariableNames(1)))(j-1);
        break;
   end
   j=j+1;
end

if(abs(app.NumericEditField9.Value-upper_bound2)>abs(app.NumericEditField9.Value-lower_bound2))
app.NumericEditField9.Value=lower_bound2;
else
    app.NumericEditField9.Value=upper_bound2;

end
% app.Currentlowerv=round(app.Currentlowerv,1);
% app.Currentupperv=round(app.Currentupperv,1);
               % rows=A.v==app.Currentlowerv;
               % if (app.Currentlowerv<app.Currentupperv)
               tolerance=1e-11;
               rows=ismembertol(A.(cell2mat(A.Properties.VariableNames(1))),app.NumericEditField8.Value,tolerance);
               ilv=find(rows==1);
               rows1=ismembertol(A.(cell2mat(A.Properties.VariableNames(1))),app.NumericEditField9.Value,tolerance);
              
               iuv=find(rows1==1);
          % new
                 y1=A.(cell2mat(A.Properties.VariableNames(2)))(ilv);
                 y2=A.(cell2mat(A.Properties.VariableNames(2)))(iuv);
               T=readtable(app.EditField.Value);
              tolerance=1e-10;
               v=ismembertol(T.(cell2mat(T.Properties.VariableNames(4))),y1,tolerance);
             
                ilvt=find(v==1);
                u=ismembertol(T.(cell2mat(T.Properties.VariableNames(4))),y2,tolerance);
                 
                 iuvt=find(u==1);
               timedatav=T.(cell2mat(T.Properties.VariableNames(4)))((ilvt):(iuvt));
               voltagedatavx=T.(cell2mat(T.Properties.VariableNames(1)))((ilvt):(iuvt));
               voltagedatavy=T.(cell2mat(T.Properties.VariableNames(2)))((ilvt):(iuvt));
               voltagedatavz=T.(cell2mat(T.Properties.VariableNames(3)))((ilvt):(iuvt));
                 voltagedatavx=voltagedatavx./app.NumericEditField3.Value;
                 voltagedatavy=voltagedatavy./app.NumericEditField4.Value;
                voltagedatavz=voltagedatavz./app.NumericEditField5.Value;
                yyaxis left
               plot(app.UIAxes,timedatav,voltagedatavx)
               plot(app.UIAxes4,timedatav,voltagedatavy)
               plot(app.UIAxes2,timedatav,voltagedatavz)
               yyaxis right
                velocitydata=A.(cell2mat(A.Properties.VariableNames(1)))((ilv):(iuv));
                timedatavv=A.(cell2mat(A.Properties.VariableNames(2)))((ilv):(iuv));
               plot(app.UIAxes,timedatavv,velocitydata)
               plot(app.UIAxes4,timedatavv,velocitydata)
               plot(app.UIAxes2,timedatavv,velocitydata)
  else 
            m=max(A.v);
      tolerance=1e-15;

 ma=ismembertol(A.(cell2mat(A.Properties.VariableNames(1))),m,tolerance);
xm=find(ma==1);
maxdata=A.(cell2mat(A.Properties.VariableNames(1)))((xm):(end));
      i=1;
while(i<(n(1)+1))
   if(maxdata(i)<=app.NumericEditField8.Value)
        upper_bound=maxdata(i);
        lower_bound=maxdata(i-1);
        break;
   end
   i=i+1;
end

if(abs(app.NumericEditField8.Value-upper_bound)>abs(app.NumericEditField8.Value-lower_bound))
app.NumericEditField8.Value=lower_bound;
else
  app.NumericEditField8.Value=upper_bound;

end
% for upperv
 j=1;


while(j<(n(1)+1))
   if(maxdata(j)<=app.NumericEditField9.Value)
        upper_bound2=maxdata(j);
        lower_bound2=maxdata(j-1);
        break;
   end
   j=j+1;
end

if(abs(app.NumericEditField9.Value-upper_bound2)>abs(app.NumericEditField9.Value-lower_bound2))
app.NumericEditField9.Value=lower_bound2;
else
    app.NumericEditField9.Value=upper_bound2;

end
                  T=readtable(app.EditField.Value);
     
v12=ismembertol(maxdata,app.NumericEditField8.Value,tolerance);
x22=find(v12==1);
v13=ismembertol(maxdata,app.NumericEditField9.Value,tolerance);
x23=find(v13==1);
x22=x22+xm-1;
x23=x23+xm-1;
x222=A.(cell2mat(A.Properties.VariableNames(2)))(x22);
x232=A.(cell2mat(A.Properties.VariableNames(2)))(x23);
tolerance1=1e-6;
 tl=ismembertol(T.(cell2mat(T.Properties.VariableNames(4))),x222,tolerance1);
 xlf=find(tl==1);
  tu=ismembertol(T.(cell2mat(T.Properties.VariableNames(4))),x232,tolerance1);
 xuf=find(tu==1);
 timedata2=T.(cell2mat(T.Properties.VariableNames(4)))((xlf):(xuf));
            voltagedatavx2=T.(cell2mat(T.Properties.VariableNames(1)))((xlf):(xuf));
               voltagedatavy2=T.(cell2mat(T.Properties.VariableNames(2)))((xlf):(xuf));
               voltagedatavz2=T.(cell2mat(T.Properties.VariableNames(3)))((xlf):(xuf));
                  voltagedatavx2=voltagedatavx2./app.NumericEditField3.Value;
                  voltagedatavy2=voltagedatavy2./app.NumericEditField4.Value;
                 voltagedatavz2=voltagedatavz2./app.NumericEditField5.Value;
               plot(app.UIAxes,timedata2,voltagedatavx2)
               plot(app.UIAxes4,timedata2,voltagedatavy2)
               plot(app.UIAxes2,timedata2,voltagedatavz2)
   end
        end

        % Button6 button pushed function
        function Button6ButtonPushed(app)
                 A=readtable(app.EditField2.Value);
n=size(A.(cell2mat(A.Properties.VariableNames(1))));
  if (app.NumericEditField8.Value<=app.NumericEditField9.Value)
i=1;
while(i<(n(1)+1))
   if(A.(cell2mat(A.Properties.VariableNames(1)))(i)>=app.NumericEditField8.Value)
        upper_bound=A.(cell2mat(A.Properties.VariableNames(1)))(i);
        lower_bound=A.(cell2mat(A.Properties.VariableNames(1)))(i-1);
        break;
   end
   i=i+1;
end

if(abs(app.NumericEditField8.Value-upper_bound)>abs(app.NumericEditField8.Value-lower_bound))
app.NumericEditField8.Value=lower_bound;
else
    app.NumericEditField8.Value=upper_bound;

end
% for upperv
 j=1;
      
while(j<(n(1)+1))
   if(A.(cell2mat(A.Properties.VariableNames(1)))(j)>=app.NumericEditField9.Value)
        upper_bound2=A.(cell2mat(A.Properties.VariableNames(1)))(j);
        lower_bound2=A.(cell2mat(A.Properties.VariableNames(1)))(j-1);
        break;
   end
   j=j+1;
end

if(abs(app.NumericEditField9.Value-upper_bound2)>abs(app.NumericEditField9.Value-lower_bound2))
app.NumericEditField9.Value=lower_bound2;
else
    app.NumericEditField9.Value=upper_bound2;

end
% app.Currentlowerv=round(app.Currentlowerv,1);
% app.Currentupperv=round(app.Currentupperv,1);
               % rows=A.v==app.Currentlowerv;
               % if (app.Currentlowerv<app.Currentupperv)
               tolerance=1e-11;
               rows=ismembertol(A.(cell2mat(A.Properties.VariableNames(1))),app.NumericEditField8.Value,tolerance);
               ilv=find(rows==1);
               rows1=ismembertol(A.(cell2mat(A.Properties.VariableNames(1))),app.NumericEditField9.Value,tolerance);
              
               iuv=find(rows1==1);
          % new
                 y1=A.(cell2mat(A.Properties.VariableNames(2)))(ilv);
                 y2=A.(cell2mat(A.Properties.VariableNames(2)))(iuv);
               T=readtable(app.EditField.Value);
              tolerance=1e-10;
               v=ismembertol(T.(cell2mat(T.Properties.VariableNames(4))),y1,tolerance);
             
                ilvt=find(v==1);
                u=ismembertol(T.(cell2mat(T.Properties.VariableNames(4))),y2,tolerance);
                 
                 iuvt=find(u==1);
               timedatav=T.(cell2mat(T.Properties.VariableNames(4)))((ilvt):(iuvt));
               voltagedatavx=T.(cell2mat(T.Properties.VariableNames(1)))((ilvt):(iuvt));
               voltagedatavy=T.(cell2mat(T.Properties.VariableNames(2)))((ilvt):(iuvt));
               voltagedatavz=T.(cell2mat(T.Properties.VariableNames(3)))((ilvt):(iuvt));
                 voltagedatavx=voltagedatavx./app.NumericEditField3.Value;
                 voltagedatavy=voltagedatavy./app.NumericEditField4.Value;
                voltagedatavz=voltagedatavz./app.NumericEditField5.Value;
               [pxx,f] = pwelch( voltagedatavx,500,0,500,app.NumericEditField2.Value);
            semilogx(app.UIAxes3,f,10*log10(pxx))
            [pxx,f] = pwelch( voltagedatavy,500,0,500,app.NumericEditField2.Value);
            semilogx(app.UIAxes5,f,10*log10(pxx))
            [pxx,f] = pwelch( voltagedatavz,500,0,500,app.NumericEditField2.Value);
           semilogx(app.UIAxes6,f,10*log10(pxx))
  else 
            m=max(A.v);
      tolerance=1e-15;

 ma=ismembertol(A.(cell2mat(A.Properties.VariableNames(1))),m,tolerance);
xm=find(ma==1);
maxdata=A.(cell2mat(A.Properties.VariableNames(1)))((xm):(end));
      i=1;
while(i<(n(1)+1))
   if(maxdata(i)<=app.NumericEditField8.Value)
        upper_bound=maxdata(i);
        lower_bound=maxdata(i-1);
        break;
   end
   i=i+1;
end

if(abs(app.NumericEditField8.Value-upper_bound)>abs(app.NumericEditField8.Value-lower_bound))
app.NumericEditField8.Value=lower_bound;
else
  app.NumericEditField8.Value=upper_bound;

end
% for upperv
 j=1;


while(j<(n(1)+1))
   if(maxdata(j)<=app.NumericEditField9.Value)
        upper_bound2=maxdata(j);
        lower_bound2=maxdata(j-1);
        break;
   end
   j=j+1;
end

if(abs(app.NumericEditField9.Value-upper_bound2)>abs(app.NumericEditField9.Value-lower_bound2))
app.NumericEditField9.Value=lower_bound2;
else
    app.NumericEditField9.Value=upper_bound2;

end
                  T=readtable(app.EditField.Value);
     
v12=ismembertol(maxdata,app.NumericEditField8.Value,tolerance);
x22=find(v12==1);
v13=ismembertol(maxdata,app.NumericEditField9.Value,tolerance);
x23=find(v13==1);
x22=x22+xm-1;
x23=x23+xm-1;
x222=A.(cell2mat(A.Properties.VariableNames(2)))(x22);
x232=A.(cell2mat(A.Properties.VariableNames(2)))(x23);
tolerance1=1e-6;
 tl=ismembertol(T.(cell2mat(T.Properties.VariableNames(4))),x222,tolerance1);
 xlf=find(tl==1);
  tu=ismembertol(T.(cell2mat(T.Properties.VariableNames(4))),x232,tolerance1);
 xuf=find(tu==1);
 timedata2=T.(cell2mat(T.Properties.VariableNames(4)))((xlf):(xuf));
            voltagedatavx2=T.(cell2mat(T.Properties.VariableNames(1)))((xlf):(xuf));
               voltagedatavy2=T.(cell2mat(T.Properties.VariableNames(2)))((xlf):(xuf));
               voltagedatavz2=T.(cell2mat(T.Properties.VariableNames(3)))((xlf):(xuf));
                  voltagedatavx2=voltagedatavx2./app.NumericEditField3.Value;
                  voltagedatavy2=voltagedatavy2./app.NumericEditField4.Value;
                 voltagedatavz2=voltagedatavz2./app.NumericEditField5.Value;
               [pxx,f] = pwelch( voltagedatavx2,500,0,500,app.NumericEditField2.Value);
           semilogx(app.UIAxes3,f,10*log10(pxx))
            [pxx,f] = pwelch( voltagedatavy2,500,0,500,app.NumericEditField2.Value);
            semilogx(app.UIAxes5,f,10*log10(pxx))
            [pxx,f] = pwelch( voltagedatavz2,500,0,500,app.NumericEditField2.Value);
           semilogx(app.UIAxes6,f,10*log10(pxx))
   end
        end

        % Button7 button pushed function
        function Button7ButtonPushed(app)
                  A=readtable(app.EditField2.Value);
 B=readtable(app.EditField.Value);
 n=size(A.(cell2mat(A.Properties.VariableNames(1))));
i=1;
while(i<(n(1)+1))
   if(A.(cell2mat(A.Properties.VariableNames(1)))(i)>=app.NumericEditField8.Value)
        upper_bound=A.(cell2mat(A.Properties.VariableNames(1)))(i);
        lower_bound=A.(cell2mat(A.Properties.VariableNames(1)))(i-1);
        break;
   end
   i=i+1;
end

if(abs(app.NumericEditField8.Value-upper_bound)>abs(app.NumericEditField8.Value-lower_bound))
app.NumericEditField8.Value=lower_bound;
else
    app.NumericEditField8.Value=upper_bound;

end

 tolerance=1e-6;
 v=ismembertol(A.(cell2mat(A.Properties.VariableNames(1))),app.NumericEditField8.Value,tolerance);
x1=find(v==1);
 y1=A.(cell2mat(A.Properties.VariableNames(2)))(x1);
  tolerance=1e-6;
 v2=ismembertol(B.(cell2mat(B.Properties.VariableNames(4))),y1,tolerance);
 x13=find(v2==1);
  m=max(A.(cell2mat(A.Properties.VariableNames(1))));
      tolerance=1e-6;

 ma=ismembertol(A.(cell2mat(A.Properties.VariableNames(1))),m,tolerance);
xm=find(ma==1);
maxdata=A.(cell2mat(A.Properties.VariableNames(1)))((xm):(end));
 j=1;


while(j<(n(1)+1))
   if(maxdata(j)<=app.NumericEditField9.Value)
        upper_bound2=maxdata(j);
        lower_bound2=maxdata(j-1);
        break;
   end
   j=j+1;
end

if(abs(app.NumericEditField9.Value-upper_bound2)>abs(app.NumericEditField9.Value-lower_bound2))
app.NumericEditField9.Value=lower_bound2;
else
    app.NumericEditField9.Value=upper_bound2;

end
v13=ismembertol(maxdata,app.NumericEditField9.Value,tolerance);
x23=find(v13==1);
x23=x23+xm-1;
x232=A.(cell2mat(A.Properties.VariableNames(2)))(x23);
tolerance1=1e-6;
 tl=ismembertol(B.(cell2mat(B.Properties.VariableNames(4))),x232,tolerance1);
 xlf=find(tl==1);
 timedatav=B.(cell2mat(B.Properties.VariableNames(4)))((x13):(xlf));
               voltagedatavx=B.(cell2mat(B.Properties.VariableNames(1)))((x13):(xlf));
               voltagedatavy=B.(cell2mat(B.Properties.VariableNames(2)))((x13):(xlf));
               voltagedatavz=B.(cell2mat(B.Properties.VariableNames(3)))((x13):(xlf));
                 voltagedatavx=voltagedatavx./app.NumericEditField3.Value;
                 voltagedatavy=voltagedatavy./app.NumericEditField4.Value;
                voltagedatavz=voltagedatavz./app.NumericEditField5.Value;
               plot(app.UIAxes,timedatav,10*log10(voltagedatavx))
               plot(app.UIAxes4,timedatav,10*log10(voltagedatavy))
               plot(app.UIAxes2,timedatav,10*log10(voltagedatavz))
        end

        % Button8 button pushed function
        function Button8ButtonPushed(app)
                              A=readtable(app.EditField2.Value);
 B=readtable(app.EditField.Value);
 n=size(A.(cell2mat(A.Properties.VariableNames(1))));
i=1;
while(i<(n(1)+1))
   if(A.(cell2mat(A.Properties.VariableNames(1)))(i)>=app.NumericEditField8.Value)
        upper_bound=A.(cell2mat(A.Properties.VariableNames(1)))(i);
        lower_bound=A.(cell2mat(A.Properties.VariableNames(1)))(i-1);
        break;
   end
   i=i+1;
end

if(abs(app.NumericEditField8.Value-upper_bound)>abs(app.NumericEditField8.Value-lower_bound))
app.NumericEditField8.Value=lower_bound;
else
    app.NumericEditField8.Value=upper_bound;

end

 tolerance=1e-6;
 v=ismembertol(A.(cell2mat(A.Properties.VariableNames(1))),app.NumericEditField8.Value,tolerance);
x1=find(v==1);
 y1=A.(cell2mat(A.Properties.VariableNames(2)))(x1);
  tolerance=1e-6;
 v2=ismembertol(B.(cell2mat(B.Properties.VariableNames(4))),y1,tolerance);
 x13=find(v2==1);
  m=max(A.(cell2mat(A.Properties.VariableNames(1))));
      tolerance=1e-6;

 ma=ismembertol(A.(cell2mat(A.Properties.VariableNames(1))),m,tolerance);
xm=find(ma==1);
maxdata=A.(cell2mat(A.Properties.VariableNames(1)))((xm):(end));
 j=1;


while(j<(n(1)+1))
   if(maxdata(j)<=app.NumericEditField9.Value)
        upper_bound2=maxdata(j);
        lower_bound2=maxdata(j-1);
        break;
   end
   j=j+1;
end

if(abs(app.NumericEditField9.Value-upper_bound2)>abs(app.NumericEditField9.Value-lower_bound2))
app.NumericEditField9.Value=lower_bound2;
else
    app.NumericEditField9.Value=upper_bound2;

end
v13=ismembertol(maxdata,app.NumericEditField9.Value,tolerance);
x23=find(v13==1);
x23=x23+xm-1;
x232=A.(cell2mat(A.Properties.VariableNames(2)))(x23);
tolerance1=1e-6;
 tl=ismembertol(B.(cell2mat(B.Properties.VariableNames(4))),x232,tolerance1);
 xlf=find(tl==1);
 timedatav=B.(cell2mat(B.Properties.VariableNames(4)))((x13):(xlf));
               voltagedatavx=B.(cell2mat(B.Properties.VariableNames(1)))((x13):(xlf));
               voltagedatavy=B.(cell2mat(B.Properties.VariableNames(2)))((x13):(xlf));
               voltagedatavz=B.(cell2mat(B.Properties.VariableNames(3)))((x13):(xlf));
                  [pxx,f] = pwelch( voltagedatavx,500,0,500,app.NumericEditField2.Value);
            plot(app.UIAxes3,f,10*log10(pxx))
            [pxx,f] = pwelch( voltagedatavy,500,0,500,app.NumericEditField2.Value);
            plot(app.UIAxes5,f,10*log10(pxx))
            [pxx,f] = pwelch( voltagedatavz,500,0,500,app.NumericEditField2.Value);
            plot(app.UIAxes6,f,10*log10(pxx))
        end

        % Button9 button pushed function
        function Button9ButtonPushed(app)
             exportgraphics(app.UIAxes,'plot.pdf','Append',true);
            exportgraphics(app.UIAxes2,'plot.pdf','Append',true);
            exportgraphics(app.UIAxes3,'plot.pdf','Append',true);
            exportgraphics(app.UIAxes4,'plot.pdf','Append',true);
            exportgraphics(app.UIAxes5,'plot.pdf','Append',true);
            exportgraphics(app.UIAxes6,'plot.pdf','Append',true);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Import';
            app.Panel.Units = 'pixels';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed);
            app.Button.Text = 'Import Signal';
            app.LabelEditField = uilabel(app.Panel);
            app.LabelEditField.Text = 'Signal File';
            app.EditField = uieditfield(app.Panel, 'text');
            app.Button2 = uibutton(app.Panel, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2ButtonPushed);
            app.Button2.Text = 'Import Velocity';
            app.LabelEditField2 = uilabel(app.Panel);
            app.LabelEditField2.Text = 'Velocity Fie';
            app.EditField2 = uieditfield(app.Panel, 'text');
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Input Parameters';
            app.Panel2.Units = 'pixels';
            app.Panel3 = uipanel(app.Panel2);
            app.Panel3.Title = 'Time';
            app.Panel3.Units = 'pixels';
            app.LabelNumericEditField6 = uilabel(app.Panel3);
            app.LabelNumericEditField6.Text = 'Lower Value of  t';
            app.NumericEditField6 = uieditfield(app.Panel3, 'numeric');
            app.LabelNumericEditField7 = uilabel(app.Panel3);
            app.LabelNumericEditField7.Text = 'Upper Value of t';
            app.NumericEditField7 = uieditfield(app.Panel3, 'numeric');
            app.Panel4 = uipanel(app.Panel2);
            app.Panel4.Title = 'Velocity';
            app.Panel4.Units = 'pixels';
            app.LabelNumericEditField8 = uilabel(app.Panel4);
            app.LabelNumericEditField8.Text = 'Lower Value of V';
            app.NumericEditField8 = uieditfield(app.Panel4, 'numeric');
            app.LabelNumericEditField9 = uilabel(app.Panel4);
            app.LabelNumericEditField9.Text = 'Upper Value of V';
            app.NumericEditField9 = uieditfield(app.Panel4, 'numeric');
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = 'Value of Sampling Rate';
            app.NumericEditField2 = uieditfield(app.UIFigure, 'numeric');
            app.Panel5 = uipanel(app.UIFigure);
            app.Panel5.Title = 'Input Sensitivities';
            app.Panel5.Units = 'pixels';
            app.LabelNumericEditField3 = uilabel(app.Panel5);
            app.LabelNumericEditField3.Text = 'Sensitivity of X';
            app.NumericEditField3 = uieditfield(app.Panel5, 'numeric');
            app.LabelNumericEditField5 = uilabel(app.Panel5);
            app.LabelNumericEditField5.Text = 'Sensitivity of Z';
            app.NumericEditField5 = uieditfield(app.Panel5, 'numeric');
            app.LabelNumericEditField4 = uilabel(app.Panel5);
            app.LabelNumericEditField4.Text = 'Sensitivity of Y';
            app.NumericEditField4 = uieditfield(app.Panel5, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'GlevelsX');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'GlevelsZ');
            xlabel(app.UIAxes2, 'X');
            ylabel(app.UIAxes2, 'Y');
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'PSDX');
            xlabel(app.UIAxes3, 'X');
            ylabel(app.UIAxes3, 'Y');
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'GlevelsY');
            xlabel(app.UIAxes4, 'X');
            ylabel(app.UIAxes4, 'Y');
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'PSDY');
            xlabel(app.UIAxes5, 'X');
            ylabel(app.UIAxes5, 'Y');
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'PSDZ');
            xlabel(app.UIAxes6, 'X');
            ylabel(app.UIAxes6, 'Y');
            app.Panel6 = uipanel(app.UIFigure);
            app.Panel6.Title = 'Plot for time';
            app.Panel6.Units = 'pixels';
            app.Button3 = uibutton(app.Panel6, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3ButtonPushed);
            app.Button3.Text = 'Glevels';
            app.Button4 = uibutton(app.Panel6, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Button4ButtonPushed);
            app.Button4.Text = 'PSD';
            app.Panel7 = uipanel(app.UIFigure);
            app.Panel7.Title = 'Plot for Velocity';
            app.Panel7.Units = 'pixels';
            app.Button5 = uibutton(app.Panel7, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @Button5ButtonPushed);
            app.Button5.Text = 'Glevels';
            app.Button6 = uibutton(app.Panel7, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @Button6ButtonPushed);
            app.Button6.Text = 'PSD';
            app.Panel8 = uipanel(app.UIFigure);
            app.Panel8.Title = 'Use if velocity are from acceleration and deceleration';
            app.Panel8.Units = 'pixels';
            app.Button7 = uibutton(app.Panel8, 'push');
            app.Button7.ButtonPushedFcn = createCallbackFcn(app, @Button7ButtonPushed);
            app.Button7.Text = 'Glevels';
            app.Button8 = uibutton(app.Panel8, 'push');
            app.Button8.ButtonPushedFcn = createCallbackFcn(app, @Button8ButtonPushed);
            app.Button8.Text = 'PSD';
            app.Button9 = uibutton(app.UIFigure, 'push');
            app.Button9.ButtonPushedFcn = createCallbackFcn(app, @Button9ButtonPushed);
            app.Button9.Text = 'Export Graphics';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2016()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end